classdef stimulus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Inter_speaker_interval         matlab.ui.control.Spinner
        InterSpeakerIntervalmsecLabel  matlab.ui.control.Label
        ISI                            matlab.ui.control.Spinner
        InterStimulusIntervalsecLabel  matlab.ui.control.Label
        EastSpeakerPanel               matlab.ui.container.Panel
        East_plot_type                 matlab.ui.control.RockerSwitch
        East_stimulus_plot             matlab.ui.control.UIAxes
        East_carrier_plot              matlab.ui.control.UIAxes
        East_modulator_plot            matlab.ui.control.UIAxes
        WestSpeakerPanel               matlab.ui.container.Panel
        West_plot_type                 matlab.ui.control.RockerSwitch
        West_stimulus_plot             matlab.ui.control.UIAxes
        West_carrier_plot              matlab.ui.control.UIAxes
        West_modulator_plot            matlab.ui.control.UIAxes
        StatusPanel                    matlab.ui.container.Panel
        LoadParametersLamp             matlab.ui.control.Lamp
        LoadParametersLampLabel        matlab.ui.control.Label
        LoadCircuitLamp                matlab.ui.control.Lamp
        LoadCircuitLampLabel           matlab.ui.control.Label
        ConnectionLamp                 matlab.ui.control.Lamp
        ConnectionLampLabel            matlab.ui.control.Label
        ControlSwitch                  matlab.ui.control.ToggleSwitch
        Speaker                        matlab.ui.control.DiscreteKnob
        TabGroup                       matlab.ui.container.TabGroup
        SearchTab                      matlab.ui.container.Tab
        Search_stimulus_duration       matlab.ui.control.Spinner
        StimulusDurationmsecLabel      matlab.ui.control.Label
        ControlPanel                   matlab.ui.container.Panel
        FrequencyCyclerHzPanel         matlab.ui.container.Panel
        End_frequency                  matlab.ui.control.NumericEditField
        EndEditFieldLabel              matlab.ui.control.Label
        Step_frequency                 matlab.ui.control.NumericEditField
        StepEditFieldLabel             matlab.ui.control.Label
        Start_frequency                matlab.ui.control.NumericEditField
        StartEditFieldLabel            matlab.ui.control.Label
        SpeciesDropDown                matlab.ui.control.DropDown
        SpeciesDropDownLabel           matlab.ui.control.Label
        ModeDropDown                   matlab.ui.control.DropDown
        ModeDropDownLabel              matlab.ui.control.Label
        ModulatorFrequenciesHzPanel    matlab.ui.container.Panel
        Fm_check_5                     matlab.ui.control.CheckBox
        Fm_5                           matlab.ui.control.NumericEditField
        Fm_check_4                     matlab.ui.control.CheckBox
        Fm_4                           matlab.ui.control.NumericEditField
        Fm_check_3                     matlab.ui.control.CheckBox
        Fm_3                           matlab.ui.control.NumericEditField
        Fm_check_2                     matlab.ui.control.CheckBox
        Fm_2                           matlab.ui.control.NumericEditField
        Fm_1                           matlab.ui.control.NumericEditField
        Fm_check_1                     matlab.ui.control.CheckBox
        CarrierFrequenciesHzPanel      matlab.ui.container.Panel
        Fc_check_5                     matlab.ui.control.CheckBox
        Fc_5                           matlab.ui.control.NumericEditField
        Fc_check_4                     matlab.ui.control.CheckBox
        Fc_4                           matlab.ui.control.NumericEditField
        Fc_check_3                     matlab.ui.control.CheckBox
        Fc_3                           matlab.ui.control.NumericEditField
        Fc_check_2                     matlab.ui.control.CheckBox
        Fc_2                           matlab.ui.control.NumericEditField
        Fc_1                           matlab.ui.control.NumericEditField
        Fc_check_1                     matlab.ui.control.CheckBox
        PRRTab                         matlab.ui.container.Tab
        ControlPanel_PRR               matlab.ui.container.Panel
        PRRCyclerppsExpandedPanel      matlab.ui.container.Panel
        Fm_PRR_end                     matlab.ui.control.NumericEditField
        EndEditFieldLabel_2            matlab.ui.control.Label
        Fm_PRR_step                    matlab.ui.control.NumericEditField
        StepEditFieldLabel_2           matlab.ui.control.Label
        Fm_PRR_start                   matlab.ui.control.NumericEditField
        StartEditFieldLabel_2          matlab.ui.control.Label
        PRRModeDropDown                matlab.ui.control.DropDown
        PRRModeDropDownLabel           matlab.ui.control.Label
        PRRppsSelPanel                 matlab.ui.container.Panel
        Fm_PRR_check_5                 matlab.ui.control.CheckBox
        Fm_PRR_5                       matlab.ui.control.NumericEditField
        Fm_PRR_check_4                 matlab.ui.control.CheckBox
        Fm_PRR_4                       matlab.ui.control.NumericEditField
        Fm_PRR_check_3                 matlab.ui.control.CheckBox
        Fm_PRR_3                       matlab.ui.control.NumericEditField
        Fm_PRR_check_2                 matlab.ui.control.CheckBox
        Fm_PRR_2                       matlab.ui.control.NumericEditField
        EnergyPanel                    matlab.ui.container.Panel
        Ec_PRR_5                       matlab.ui.control.NumericEditField
        Ec_PRR_4                       matlab.ui.control.NumericEditField
        Ec_PRR_3                       matlab.ui.control.NumericEditField
        Ec_PRR_2                       matlab.ui.control.NumericEditField
        Ec_PRR_1                       matlab.ui.control.NumericEditField
        CarrierFrequenciesHzPanel_2    matlab.ui.container.Panel
        Fm_PRR_1                       matlab.ui.control.NumericEditField
        Fm_PRR_check_1                 matlab.ui.control.CheckBox
        Fc_PRR_check_5                 matlab.ui.control.CheckBox
        Fc_PRR_5                       matlab.ui.control.NumericEditField
        Fc_PRR_check_4                 matlab.ui.control.CheckBox
        Fc_PRR_4                       matlab.ui.control.NumericEditField
        Fc_PRR_check_3                 matlab.ui.control.CheckBox
        Fc_PRR_3                       matlab.ui.control.NumericEditField
        Fc_PRR_check_2                 matlab.ui.control.CheckBox
        Fc_PRR_2                       matlab.ui.control.NumericEditField
        Fc_PRR_1                       matlab.ui.control.NumericEditField
        Fc_PRR_check_1                 matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        RPcontroller % actxcontrol of RP2.1 processor
    end
    
    methods (Access = private)

        function [n, signal_fft] = compute_fft(~, signal, time)
            samples = size(time, 2);
            n = 2^nextpow2(samples);
            signal_fft = fft(signal, n, 2);
            signal_fft = abs(signal_fft/samples);
            signal_fft = signal_fft(:, 1:n/2+1);
            signal_fft(:, 2:end-1) = 2*signal_fft(:, 2:end-1);
        end
        
        function hardware_initialization(app)
            app.RPcontroller = actxserver('RPco.x');
            if app.RPcontroller.ConnectRP2('GB', 1)
                app.ConnectionLamp.Color = [0, 1, 0];
            else
                app.ConnectionLamp.Color = [1, 0, 0];
            end
        end
        
        function e = load_circuit(app, circuit)
            e = app.RPcontroller.LoadCOF(circuit);
            if e
                app.LoadCircuitLamp.Color = [0, 1, 0];
            else
                app.LoadCircuitLamp.Color = [1, 0, 0];
            end
        end

        %% Search Tab Functions
        function search_mode_normal(app)
            % Search Tab normal mode configuration.
            app.Fc_check_1.Value = 1;
            app.Fc_check_2.Value = 1;
            app.Fc_check_3.Value = 1;
            app.Fc_check_4.Value = 1;
            app.Fc_check_5.Value = 1;
            app.Fc_check_1ValueChanged();
            app.Fc_check_2ValueChanged();
            app.Fc_check_3ValueChanged();
            app.Fc_check_4ValueChanged();
            app.Fc_check_5ValueChanged();
            app.Fm_check_1.Value = 1;
            app.Fm_check_2.Value = 1;
            app.Fm_check_3.Value = 1;
            app.Fm_check_4.Value = 1;
            app.Fm_check_5.Value = 1;
            app.Fm_check_1ValueChanged();
            app.Fm_check_2ValueChanged();
            app.Fm_check_3ValueChanged();
            app.Fm_check_4ValueChanged();
            app.Fm_check_5ValueChanged();
            app.Start_frequency.Enable = 0;
            app.Step_frequency.Enable = 0;
            app.End_frequency.Enable = 0;
        end
        
        function search_mode_fixed_carrier(app)
            % Fixed carrier configuration.
            app.Fc_check_1.Value = 0;
            app.Fc_check_2.Value = 0;
            app.Fc_check_3.Value = 0;
            app.Fc_check_4.Value = 0;
            app.Fc_check_5.Value = 1;
            app.Fc_check_1ValueChanged();
            app.Fc_check_2ValueChanged();
            app.Fc_check_3ValueChanged();
            app.Fc_check_4ValueChanged();
            app.Fc_check_5ValueChanged();
            app.Fm_check_1.Value = 0;
            app.Fm_check_2.Value = 0;
            app.Fm_check_3.Value = 0;
            app.Fm_check_4.Value = 0;
            app.Fm_check_5.Value = 0;
            app.Fm_check_1ValueChanged();
            app.Fm_check_2ValueChanged();
            app.Fm_check_3ValueChanged();
            app.Fm_check_4ValueChanged();
            app.Fm_check_5ValueChanged();
            app.Start_frequency.Enable = 1;
            app.Start_frequency.Value = 10;
            app.Step_frequency.Enable = 1;
            app.Step_frequency.Value = 10;
            app.End_frequency.Enable = 1;
            app.End_frequency.Value = 80;
        end
        
        function search_mode_fixed_modulation(app)
            % Fixed modulation configuration.
            app.Fc_check_1.Value = 0;
            app.Fc_check_2.Value = 0;
            app.Fc_check_3.Value = 0;
            app.Fc_check_4.Value = 0;
            app.Fc_check_5.Value = 0;
            app.Fc_check_1ValueChanged();
            app.Fc_check_2ValueChanged();
            app.Fc_check_3ValueChanged();
            app.Fc_check_4ValueChanged();
            app.Fc_check_5ValueChanged();
            app.Fm_check_1.Value = 0;
            app.Fm_check_2.Value = 0;
            app.Fm_check_3.Value = 0;
            app.Fm_check_4.Value = 0;
            app.Fm_check_5.Value = 1;
            app.Fm_check_1ValueChanged();
            app.Fm_check_2ValueChanged();
            app.Fm_check_3ValueChanged();
            app.Fm_check_4ValueChanged();
            app.Fm_check_5ValueChanged();
            app.Start_frequency.Enable = 1;
            app.Start_frequency.Value = 300;
            app.Step_frequency.Enable = 1;
            app.Step_frequency.Value = 20;
            app.End_frequency.Enable = 1;
            app.End_frequency.Value = 600;
        end
        
        function freqs = search_get_carriers(app)
            % Collect active carrier frequencies.
            mode = app.ModeDropDown.Value;
            if mode == "Fixed Modulation"
                freqs = app.Start_frequency.Value: app.Step_frequency.Value: app.End_frequency.Value;
            else
                freqs = [];
                if app.Fc_check_1.Value == 1
                    freqs(end+1) = app.Fc_1.Value;
                end
                if app.Fc_check_2.Value == 1
                    freqs(end+1) = app.Fc_2.Value;
                end
                if app.Fc_check_3.Value == 1
                    freqs(end+1) = app.Fc_3.Value;
                end
                if app.Fc_check_4.Value == 1
                    freqs(end+1) = app.Fc_4.Value;
                end
                if app.Fc_check_5.Value == 1
                    freqs(end+1) = app.Fc_5.Value;
                end
            end
        end
        
        function freqs = search_get_modulators(app)
            % Collect modulation frequencies.
            mode = app.ModeDropDown.Value;
            if mode == "Fixed Carrier"
                freqs = app.Start_frequency.Value: app.Step_frequency.Value: app.End_frequency.Value;
            else
                freqs = [];
                if app.Fm_check_1.Value == 1
                    freqs(end+1) = app.Fm_1.Value;
                end
                if app.Fm_check_2.Value == 1
                    freqs(end+1) = app.Fm_2.Value;
                end
                if app.Fm_check_3.Value == 1
                    freqs(end+1) = app.Fm_3.Value;
                end
                if app.Fm_check_4.Value == 1
                    freqs(end+1) = app.Fm_4.Value;
                end
                if app.Fm_check_5.Value == 1
                    freqs(end+1) = app.Fm_5.Value;
                end
            end
        end
        
        function [time, carrier, modulator, stimulus, stimulus_trigger, pulse_trigger] = build_AM(app, carrier_frequency, modulator_frequency, append_time, region)
            % Generate Search Stimulus.
            sampling_rate = 24414.0625; % Sampling Rate of TDT System III RP2.1. Units: Hz.
            stimulus_duration = app.Search_stimulus_duration.Value*1e-3; % Inter-stimulus interval. Converted from msec to sec.
            append_samples = floor(sampling_rate*append_time);
            time = 0: (1/sampling_rate) : (stimulus_duration - (1/sampling_rate)); % Array of time samples.
            stimulus_trigger = zeros(size(time));
            stimulus_trigger(1:end, 1) = 1;
            pulse_trigger = zeros(size(time));
            pulse_trigger(1:end, 1) = 1;
            if region == "start"
                carrier = cat(2, zeros(1, append_samples), sin(2*pi*carrier_frequency.*time)); % Generate carrier sinusoid for all carrier frequencies.
                modulator = cat(2, zeros(1, append_samples), sin(2*pi*modulator_frequency.*time)); % Generate modulator sinusoid for all modulator frequencies.
                stimulus_trigger = cat(2, zeros(1, append_samples), stimulus_trigger);
                pulse_trigger = cat(2, zeros(1, append_samples), pulse_trigger);
            elseif region == "end"
                carrier = cat(2, sin(2*pi*carrier_frequency.*time), zeros(1, append_samples));
                modulator = cat(2, sin(2*pi*modulator_frequency.*time), zeros(1, append_samples));
                stimulus_trigger = cat(2, stimulus_trigger, zeros(1, append_samples));
                pulse_trigger = cat(2, pulse_trigger, zeros(1, append_samples));
            end
            time = 0: (1/sampling_rate): ((stimulus_duration+append_time) - (1/sampling_rate));
            stimulus = 10.*carrier .* modulator; % Generate stimuli.
        end
        
        function [time, carrier, modulator, stimulus, stimulus_trigger, pulse_trigger] = build_biaural_search_stimulus(app, carrier_frequency, modulator_frequency)
            speaker_interval = app.Inter_speaker_interval.Value*1e-3;
            if speaker_interval >= 0
                [West.time, West.carrier, West.modulator, West.stimulus, West.stimulus_trigger, West.pulse_trigger] = app.build_AM(carrier_frequency, modulator_frequency, abs(speaker_interval), "end");
                [East.time, East.carrier, East.modulator, East.stimulus, East.stimulus_trigger, East.pulse_trigger] = app.build_AM(carrier_frequency, modulator_frequency, abs(speaker_interval), "start");
            elseif speaker_interval < 0
                [West.time, West.carrier, West.modulator, West.stimulus, West.stimulus_trigger, West.pulse_trigger] = app.build_AM(carrier_frequency, modulator_frequency, abs(speaker_interval), "start");
                [East.time, East.carrier, East.modulator, East.stimulus, East.stimulus_trigger, East.pulse_trigger] = app.build_AM(carrier_frequency, modulator_frequency, abs(speaker_interval), "end");
            end
            if app.Speaker.Value == "East"
                West.carrier = West.carrier .* 0;
                West.modulator = West.modulator .* 0;
                West.stimulus = West.stimulus .* 0;
                West.stimulus_trigger = West.stimulus_trigger .* 0;
                West.pulse_trigger = West.pulse_trigger .* 0;
            elseif app.Speaker.Value == "West"
                East.carrier = East.carrier .* 0;
                East.modulator = East.modulator .* 0;
                East.stimulus = East.stimulus .* 0;
                East.stimulus_trigger = East.stimulus_trigger .* 0;
                East.pulse_trigger = East.pulse_trigger .* 0;
            end
            time = East.time;
            carrier = cat(1, East.carrier, West.carrier);
            modulator = cat(1, East.modulator, West.modulator);
            stimulus = cat(1, East.stimulus, West.stimulus);
            stimulus_trigger = cat(1, East.stimulus_trigger, West.stimulus_trigger);
            pulse_trigger = cat(1, East.pulse_trigger, West.pulse_trigger);
        end
        
        function e = transfer_search_parameters(app, stimulus, stimulus_trigger, pulse_trigger)
            e = [];
            e(end+1) = app.RPcontroller.WriteTagV('EastPulseTrigger', 0, pulse_trigger(1, :));
            e(end+1) = app.RPcontroller.WriteTagV('EastStimulusTrigger', 0, stimulus_trigger(1, :));
            e(end+1) = app.RPcontroller.WriteTagV('EastStimulus', 0, stimulus(1, :));
            e(end+1) = app.RPcontroller.SetTagVal('EastStimulusPoints', size(stimulus, 2));
            e(end+1) = app.RPcontroller.WriteTagV('WestPulseTrigger', 0, pulse_trigger(2, :));
            e(end+1) = app.RPcontroller.WriteTagV('WestStimulusTrigger', 0, stimulus_trigger(2, :));
            e(end+1) = app.RPcontroller.WriteTagV('WestStimulus', 0, stimulus(2, :));
            e(end+1) = app.RPcontroller.SetTagVal('WestStimulusPoints', size(stimulus, 2));
            e = prod(e, 'all');
            if e
                app.LoadParametersLamp.Color = [0, 1, 0];
            else
                app.LoadParametersLamp.Color = [1, 0, 0];
            end
        end
        
        function [carrier_freq, carrier_fft, modulator_freq, modulator_fft, stimulus_fft] = get_fft(app, time, carrier, modulator, stimulus, max_fc, max_fm)
            sampling_rate = 24144.0625; % TDT System 3 sampling rate.
            [~, carrier_fft] = app.compute_fft(carrier, time);
            [~, modulator_fft] = app.compute_fft(modulator, time);
            [n, stimulus_fft] = app.compute_fft(stimulus, time);
            freq = 0:(sampling_rate/n):(sampling_rate/2 - sampling_rate/n);
            carrier_freq = freq(freq <= (max_fc+200));
            modulator_freq = freq(freq <= (max_fm+20));
            carrier_fft = carrier_fft(:, 1:size(carrier_freq, 2));
            modulator_fft = modulator_fft(:, 1:size(modulator_freq, 2));
            stimulus_fft = stimulus_fft(:, 1:size(carrier_freq, 2));
        end
        
        function search_plot_stimuli(app, time, carrier, modulator, stimulus, max_fc, max_fm)
            plot_type = app.East_plot_type.Value;
            if plot_type == "Time"
                plot(app.East_carrier_plot, carrier(1, :), 'LineWidth', 0.5, "XData", time);
                plot(app.East_modulator_plot, modulator(1, :), 'LineWidth', 0.5, "XData", time);
                plot(app.East_stimulus_plot, stimulus(1, :), 'LineWidth', 0.5, "XData", time);
            elseif plot_type == "FFT"
                [carrier_freq, carrier_fft, modulator_freq, modulator_fft, stimulus_fft] = app.get_fft(time, carrier(1, :), modulator(1, :), stimulus(1, :), max_fc, max_fm);
                plot(app.East_carrier_plot, carrier_fft, 'LineWidth', 0.5, "XData", carrier_freq);
                plot(app.East_modulator_plot, modulator_fft, 'LineWidth', 0.5, "XData", modulator_freq);
                plot(app.East_stimulus_plot, stimulus_fft, 'LineWidth', 0.5, "XData", carrier_freq);
            end
            plot_type = app.West_plot_type.Value;
            if plot_type == "Time"
                plot(app.West_carrier_plot, carrier(2, :), 'LineWidth', 0.5, "XData", time);
                plot(app.West_modulator_plot, modulator(2, :), 'LineWidth', 0.5, "XData", time);
                plot(app.West_stimulus_plot, stimulus(2, :), 'LineWidth', 0.5, "XData", time);
            elseif plot_type == "FFT"
                [carrier_freq, carrier_fft, modulator_freq, modulator_fft, stimulus_fft] = app.get_fft(time, carrier(2, :), modulator(2, :), stimulus(2, :), max_fc, max_fm);
                plot(app.West_carrier_plot, carrier_fft, 'LineWidth', 0.5, "XData", carrier_freq);
                plot(app.West_modulator_plot, modulator_fft, 'LineWidth', 0.5, "XData", modulator_freq);
                plot(app.West_stimulus_plot, stimulus_fft, 'LineWidth', 0.5, "XData", carrier_freq);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.hardware_initialization();
            app.TabGroupSelectionChanged();
        end

        % Value changed function: SpeciesDropDown
        function SpeciesDropDownValueChanged(app, event)
            value = app.SpeciesDropDown.Value;
            if value == "Rana pippiens"
                app.Fc_5.Value = 1600;
            elseif value == "Hyla versicolor"
                app.Fc_5.Value = 2200;
            elseif value == "Hyla chrysocelis"
                app.Fc_5.Value = 2200;
            elseif value == "Pseudacris feriarum"
                app.Fc_5.Value = 3100;
            end
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            if value == "Normal"
                app.search_mode_normal();
            elseif value == "Fixed Carrier"
                app.search_mode_fixed_carrier();
            elseif value == "Fixed Modulation"
                app.search_mode_fixed_modulation();
            end
        end

        % Value changed function: Fc_check_1
        function Fc_check_1ValueChanged(app, event)
            value = app.Fc_check_1.Value;
            if value == 0
                app.Fc_1.Enable = 0;
            else
                app.Fc_1.Enable = 1;
            end
        end

        % Value changed function: Fc_check_2
        function Fc_check_2ValueChanged(app, event)
            value = app.Fc_check_2.Value;
            if value == 0
                app.Fc_2.Enable = 0;
            else
                app.Fc_2.Enable = 1;
            end
        end

        % Value changed function: Fc_check_3
        function Fc_check_3ValueChanged(app, event)
            value = app.Fc_check_3.Value;
            if value == 0
                app.Fc_3.Enable = 0;
            else
                app.Fc_3.Enable = 1;
            end
        end

        % Value changed function: Fc_check_4
        function Fc_check_4ValueChanged(app, event)
            value = app.Fc_check_4.Value;
            if value == 0
                app.Fc_4.Enable = 0;
            else
                app.Fc_4.Enable = 1;
            end
        end

        % Value changed function: Fc_check_5
        function Fc_check_5ValueChanged(app, event)
            value = app.Fc_check_5.Value;
            if value == 0
                app.Fc_5.Enable = 0;
            else
                app.Fc_5.Enable = 1;
            end
        end

        % Value changed function: Fm_check_1
        function Fm_check_1ValueChanged(app, event)
            value = app.Fm_check_1.Value;
            if value == 0
                app.Fm_1.Enable = 0;
            else
                app.Fm_1.Enable = 1;
            end
        end

        % Value changed function: Fm_check_2
        function Fm_check_2ValueChanged(app, event)
            value = app.Fm_check_2.Value;
            if value == 0
                app.Fm_2.Enable = 0;
            else
                app.Fm_2.Enable = 1;
            end
        end

        % Value changed function: Fm_check_3
        function Fm_check_3ValueChanged(app, event)
            value = app.Fm_check_3.Value;
            if value == 0
                app.Fm_3.Enable = 0;
            else
                app.Fm_3.Enable = 1;
            end
        end

        % Value changed function: Fm_check_4
        function Fm_check_4ValueChanged(app, event)
            value = app.Fm_check_4.Value;
            if value == 0
                app.Fm_4.Enable = 0;
            else
                app.Fm_4.Enable = 1;
            end
        end

        % Value changed function: Fm_check_5
        function Fm_check_5ValueChanged(app, event)
            value = app.Fm_check_5.Value;
            if value == 0
                app.Fm_5.Enable = 0;
            else
                app.Fm_5.Enable = 1;
            end
        end

        % Value changed function: ControlSwitch
        function ControlSwitchValueChanged(app, event)
            while app.ControlSwitch.Value == "On"
                tab = app.TabGroup.SelectedTab.Title;
                if tab == "Search"
                    fcs = app.search_get_carriers();
                    fms = app.search_get_modulators();
                    for i = 1: 1: size(fcs, 2)
                        for j = 1: 1: size(fms, 2)
                            if app.ControlSwitch.Value == "Off"
                                return;
                            end
                            [time, carrier, modulator, stimulus, stimulus_trigger, pulse_trigger] = app.build_biaural_search_stimulus(fcs(1, i), fms(1, j));
                            app.RPcontroller.ClearCOF();
                            if app.load_circuit("biaural_circuit.rcx")
                                if app.transfer_search_parameters(stimulus, stimulus_trigger, pulse_trigger)
                                    app.RPcontroller.Run();
                                    app.RPcontroller.SoftTrg(1);
                                    app.RPcontroller.SoftTrg(2);
                                    app.search_plot_stimuli(time, carrier, modulator, stimulus, max(fcs), max(fms));
                                    pause(app.ISI.Value);
                                else
                                    return;
                                end
                            else
                                return;
                            end
                        end
                    end
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.ControlSwitch.Value = "Off";
            delete(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)

        end

        % Value changed function: Speaker
        function SpeakerValueChanged(app, event)
            value = app.Speaker.Value;
            if value == "Biaural"
                app.Inter_speaker_interval.Enable = 1;
            else
                app.Inter_speaker_interval.Enable = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SearchTab = uitab(app.TabGroup);
            app.SearchTab.Title = 'Search';
            app.ControlPanel = uipanel(app.SearchTab);
            app.ControlPanel.Title = 'Control';
            app.CarrierFrequenciesHzPanel = uipanel(app.ControlPanel);
            app.CarrierFrequenciesHzPanel.Title = 'Carrier Frequencies (Hz)';
            app.Fc_check_1 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_1.ValueChangedFcn = createCallbackFcn(app, @Fc_check_1ValueChanged, true);
            app.Fc_check_1.Text = '';
            app.Fc_check_1.Value = true;
            app.Fc_1 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_1.Value = 150;
            app.Fc_2 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_2.Value = 300;
            app.Fc_check_2 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_2.ValueChangedFcn = createCallbackFcn(app, @Fc_check_2ValueChanged, true);
            app.Fc_check_2.Text = '';
            app.Fc_check_2.Value = true;
            app.Fc_3 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_3.Value = 600;
            app.Fc_check_3 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_3.ValueChangedFcn = createCallbackFcn(app, @Fc_check_3ValueChanged, true);
            app.Fc_check_3.Text = '';
            app.Fc_check_3.Value = true;
            app.Fc_4 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_4.Value = 1100;
            app.Fc_check_4 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_4.ValueChangedFcn = createCallbackFcn(app, @Fc_check_4ValueChanged, true);
            app.Fc_check_4.Text = '';
            app.Fc_check_4.Value = true;
            app.Fc_5 = uieditfield(app.CarrierFrequenciesHzPanel, 'numeric');
            app.Fc_5.Value = 1600;
            app.Fc_check_5 = uicheckbox(app.CarrierFrequenciesHzPanel);
            app.Fc_check_5.ValueChangedFcn = createCallbackFcn(app, @Fc_check_5ValueChanged, true);
            app.Fc_check_5.Text = '';
            app.Fc_check_5.Value = true;
            app.ModulatorFrequenciesHzPanel = uipanel(app.ControlPanel);
            app.ModulatorFrequenciesHzPanel.Title = 'Modulator Frequencies (Hz)';
            app.Fm_check_1 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_1.ValueChangedFcn = createCallbackFcn(app, @Fm_check_1ValueChanged, true);
            app.Fm_check_1.Text = '';
            app.Fm_check_1.Value = true;
            app.Fm_1 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_1.Value = 10;
            app.Fm_2 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_2.Value = 20;
            app.Fm_check_2 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_2.ValueChangedFcn = createCallbackFcn(app, @Fm_check_2ValueChanged, true);
            app.Fm_check_2.Text = '';
            app.Fm_check_2.Value = true;
            app.Fm_3 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_3.Value = 40;
            app.Fm_check_3 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_3.ValueChangedFcn = createCallbackFcn(app, @Fm_check_3ValueChanged, true);
            app.Fm_check_3.Text = '';
            app.Fm_check_3.Value = true;
            app.Fm_4 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_4.Value = 60;
            app.Fm_check_4 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_4.ValueChangedFcn = createCallbackFcn(app, @Fm_check_4ValueChanged, true);
            app.Fm_check_4.Text = '';
            app.Fm_check_4.Value = true;
            app.Fm_5 = uieditfield(app.ModulatorFrequenciesHzPanel, 'numeric');
            app.Fm_5.Value = 80;
            app.Fm_check_5 = uicheckbox(app.ModulatorFrequenciesHzPanel);
            app.Fm_check_5.ValueChangedFcn = createCallbackFcn(app, @Fm_check_5ValueChanged, true);
            app.Fm_check_5.Text = '';
            app.Fm_check_5.Value = true;
            app.ModeDropDownLabel = uilabel(app.ControlPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.ControlPanel);
            app.ModeDropDown.Items = {'Normal', 'Fixed Carrier', 'Fixed Modulation'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Normal';
            app.SpeciesDropDownLabel = uilabel(app.ControlPanel);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.SpeciesDropDown = uidropdown(app.ControlPanel);
            app.SpeciesDropDown.Items = {'Rana pippiens', 'Hyla versicolor', 'Hyla chrysocelis', 'Pseudacris feriarum'};
            app.SpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @SpeciesDropDownValueChanged, true);
            app.SpeciesDropDown.Value = 'Rana pippiens';
            app.FrequencyCyclerHzPanel = uipanel(app.ControlPanel);
            app.FrequencyCyclerHzPanel.Title = 'Frequency Cycler (Hz)';
            app.StartEditFieldLabel = uilabel(app.FrequencyCyclerHzPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.Start_frequency = uieditfield(app.FrequencyCyclerHzPanel, 'numeric');
            app.Start_frequency.Value = 300;
            app.StepEditFieldLabel = uilabel(app.FrequencyCyclerHzPanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.Step_frequency = uieditfield(app.FrequencyCyclerHzPanel, 'numeric');
            app.Step_frequency.Value = 20;
            app.EndEditFieldLabel = uilabel(app.FrequencyCyclerHzPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.End_frequency = uieditfield(app.FrequencyCyclerHzPanel, 'numeric');
            app.End_frequency.Value = 600;
            app.StimulusDurationmsecLabel = uilabel(app.SearchTab);
            app.StimulusDurationmsecLabel.Text = 'Stimulus Duration (msec)';
            app.Search_stimulus_duration = uispinner(app.SearchTab);
            app.Search_stimulus_duration.Step = 50;
            app.Search_stimulus_duration.Value = 400;
            app.PRRTab = uitab(app.TabGroup);
            app.PRRTab.Title = 'PRR';
            app.ControlPanel_PRR = uipanel(app.PRRTab);
            app.ControlPanel_PRR.Title = 'Control';
            app.CarrierFrequenciesHzPanel_2 = uipanel(app.ControlPanel_PRR);
            app.CarrierFrequenciesHzPanel_2.Title = 'Carrier Frequencies (Hz)';
            app.Fc_PRR_check_1 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_1.Enable = 'off';
            app.Fc_PRR_check_1.Text = '';
            app.Fc_PRR_1 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_1.Enable = 'off';
            app.Fc_PRR_1.Value = 150;
            app.Fc_PRR_2 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_2.Enable = 'off';
            app.Fc_PRR_2.Value = 300;
            app.Fc_PRR_check_2 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_2.Enable = 'off';
            app.Fc_PRR_check_2.Text = '';
            app.Fc_PRR_3 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_3.Enable = 'off';
            app.Fc_PRR_3.Value = 600;
            app.Fc_PRR_check_3 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_3.Enable = 'off';
            app.Fc_PRR_check_3.Text = '';
            app.Fc_PRR_4 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_4.Enable = 'off';
            app.Fc_PRR_4.Value = 1100;
            app.Fc_PRR_check_4 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_4.Enable = 'off';
            app.Fc_PRR_check_4.Text = '';
            app.Fc_PRR_5 = uieditfield(app.CarrierFrequenciesHzPanel_2, 'numeric');
            app.Fc_PRR_5.Value = 1600;
            app.Fc_PRR_check_5 = uicheckbox(app.CarrierFrequenciesHzPanel_2);
            app.Fc_PRR_check_5.Text = '';
            app.Fc_PRR_check_5.Value = true;
            app.EnergyPanel = uipanel(app.ControlPanel_PRR);
            app.EnergyPanel.Title = 'Energy';
            app.Ec_PRR_1 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_1.Enable = 'off';
            app.Ec_PRR_2 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_2.Enable = 'off';
            app.Ec_PRR_3 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_3.Enable = 'off';
            app.Ec_PRR_4 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_4.Enable = 'off';
            app.Ec_PRR_5 = uieditfield(app.EnergyPanel, 'numeric');
            app.Ec_PRR_5.Value = 1;
            app.PRRppsSelPanel = uipanel(app.ControlPanel_PRR);
            app.PRRppsSelPanel.Title = 'PRR (pps): Sel';
            app.Fm_PRR_check_1 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_1.Text = '';
            app.Fm_PRR_check_1.Value = true;
            app.Fm_PRR_1 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_1.Value = 10;
            app.Fm_PRR_2 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_2.Value = 20;
            app.Fm_PRR_check_2 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_2.Text = '';
            app.Fm_PRR_check_2.Value = true;
            app.Fm_PRR_3 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_3.Value = 40;
            app.Fm_PRR_check_3 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_3.Text = '';
            app.Fm_PRR_check_3.Value = true;
            app.Fm_PRR_4 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_4.Value = 60;
            app.Fm_PRR_check_4 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_4.Text = '';
            app.Fm_PRR_check_4.Value = true;
            app.Fm_PRR_5 = uieditfield(app.PRRppsSelPanel, 'numeric');
            app.Fm_PRR_5.Value = 80;
            app.Fm_PRR_check_5 = uicheckbox(app.PRRppsSelPanel);
            app.Fm_PRR_check_5.Text = '';
            app.Fm_PRR_check_5.Value = true;
            app.PRRModeDropDownLabel = uilabel(app.ControlPanel_PRR);
            app.PRRModeDropDownLabel.Text = 'PRR Mode';
            app.PRRModeDropDown = uidropdown(app.ControlPanel_PRR);
            app.PRRModeDropDown.Items = {'Selected', 'Expanded'};
            app.PRRModeDropDown.Value = 'Selected';
            app.PRRCyclerppsExpandedPanel = uipanel(app.ControlPanel_PRR);
            app.PRRCyclerppsExpandedPanel.Title = 'PRR Cycler (pps): Expanded';
            app.StartEditFieldLabel_2 = uilabel(app.PRRCyclerppsExpandedPanel);
            app.StartEditFieldLabel_2.Enable = 'off';
            app.StartEditFieldLabel_2.Text = 'Start';
            app.Fm_PRR_start = uieditfield(app.PRRCyclerppsExpandedPanel, 'numeric');
            app.Fm_PRR_start.Enable = 'off';
            app.Fm_PRR_start.Value = 10;
            app.StepEditFieldLabel_2 = uilabel(app.PRRCyclerppsExpandedPanel);
            app.StepEditFieldLabel_2.Enable = 'off';
            app.StepEditFieldLabel_2.Text = 'Step';
            app.Fm_PRR_step = uieditfield(app.PRRCyclerppsExpandedPanel, 'numeric');
            app.Fm_PRR_step.Enable = 'off';
            app.Fm_PRR_step.Value = 10;
            app.EndEditFieldLabel_2 = uilabel(app.PRRCyclerppsExpandedPanel);
            app.EndEditFieldLabel_2.Enable = 'off';
            app.EndEditFieldLabel_2.Text = 'End';
            app.Fm_PRR_end = uieditfield(app.PRRCyclerppsExpandedPanel, 'numeric');
            app.Fm_PRR_end.Enable = 'off';
            app.Fm_PRR_end.Value = 60;
            app.Speaker = uiknob(app.UIFigure, 'discrete');
            app.Speaker.Items = {'East', 'Biaural', 'West'};
            app.Speaker.ValueChangedFcn = createCallbackFcn(app, @SpeakerValueChanged, true);
            app.Speaker.Value = 'West';
            app.ControlSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ControlSwitch.ValueChangedFcn = createCallbackFcn(app, @ControlSwitchValueChanged, true);
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.ConnectionLampLabel = uilabel(app.StatusPanel);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.StatusPanel);
            app.LoadCircuitLampLabel = uilabel(app.StatusPanel);
            app.LoadCircuitLampLabel.Text = 'Load Circuit';
            app.LoadCircuitLamp = uilamp(app.StatusPanel);
            app.LoadParametersLampLabel = uilabel(app.StatusPanel);
            app.LoadParametersLampLabel.Text = 'Load Parameters';
            app.LoadParametersLamp = uilamp(app.StatusPanel);
            app.WestSpeakerPanel = uipanel(app.UIFigure);
            app.WestSpeakerPanel.Title = 'West Speaker';
            app.West_modulator_plot = uiaxes(app.WestSpeakerPanel);
            title(app.West_modulator_plot, 'Modulator')
            xlabel(app.West_modulator_plot, 'time (sec)')
            ylabel(app.West_modulator_plot, 'Amplitude (V)')
            zlabel(app.West_modulator_plot, 'Z')
            app.West_carrier_plot = uiaxes(app.WestSpeakerPanel);
            title(app.West_carrier_plot, 'Carrier')
            xlabel(app.West_carrier_plot, 'time (sec)')
            ylabel(app.West_carrier_plot, 'Amplitude (V)')
            zlabel(app.West_carrier_plot, 'Z')
            app.West_stimulus_plot = uiaxes(app.WestSpeakerPanel);
            title(app.West_stimulus_plot, 'Stimulus')
            xlabel(app.West_stimulus_plot, 'time (sec)')
            ylabel(app.West_stimulus_plot, 'Amplitude (V)')
            zlabel(app.West_stimulus_plot, 'Z')
            app.West_plot_type = uiswitch(app.WestSpeakerPanel, 'rocker');
            app.West_plot_type.Items = {'Time', 'FFT'};
            app.West_plot_type.Value = 'Time';
            app.EastSpeakerPanel = uipanel(app.UIFigure);
            app.EastSpeakerPanel.Title = 'East Speaker';
            app.East_modulator_plot = uiaxes(app.EastSpeakerPanel);
            title(app.East_modulator_plot, 'Modulator')
            xlabel(app.East_modulator_plot, 'time (sec)')
            ylabel(app.East_modulator_plot, 'Amplitude (V)')
            zlabel(app.East_modulator_plot, 'Z')
            app.East_carrier_plot = uiaxes(app.EastSpeakerPanel);
            title(app.East_carrier_plot, 'Carrier')
            xlabel(app.East_carrier_plot, 'time (sec)')
            ylabel(app.East_carrier_plot, 'Amplitude (V)')
            zlabel(app.East_carrier_plot, 'Z')
            app.East_stimulus_plot = uiaxes(app.EastSpeakerPanel);
            title(app.East_stimulus_plot, 'Stimulus')
            xlabel(app.East_stimulus_plot, 'time (sec)')
            ylabel(app.East_stimulus_plot, 'Amplitude (V)')
            zlabel(app.East_stimulus_plot, 'Z')
            app.East_plot_type = uiswitch(app.EastSpeakerPanel, 'rocker');
            app.East_plot_type.Items = {'Time', 'FFT'};
            app.East_plot_type.Value = 'Time';
            app.InterStimulusIntervalsecLabel = uilabel(app.UIFigure);
            app.InterStimulusIntervalsecLabel.Text = 'Inter-Stimulus Interval (sec)';
            app.ISI = uispinner(app.UIFigure);
            app.ISI.Step = 0.5;
            app.ISI.Value = 2;
            app.InterSpeakerIntervalmsecLabel = uilabel(app.UIFigure);
            app.InterSpeakerIntervalmsecLabel.Text = 'Inter-Speaker Interval (msec)';
            app.Inter_speaker_interval = uispinner(app.UIFigure);
            app.Inter_speaker_interval.Step = 5;
            app.Inter_speaker_interval.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stimulus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end